classdef ADAMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        BatteryHansmodelButton         matlab.ui.control.Button
        BatteryLinearmodelButton       matlab.ui.control.Button
        DieselGenQuadraticmodelButton  matlab.ui.control.Button
        LoadFixedButton                matlab.ui.control.Button
        SolarPanelTimeseriesmodelButton  matlab.ui.control.Button
        LoadSheddableButton            matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        SimulateButton                 matlab.ui.control.Button
        ViewresultsButton              matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        macrogridRow                   matlab.ui.container.GridLayout
        macrogridImage                 matlab.ui.control.Image
        elementContainer               matlab.ui.container.GridLayout
        RightPanel                     matlab.ui.container.Panel
        outterGrid                     matlab.ui.container.GridLayout
        inputContainer                 matlab.ui.container.GridLayout
        DeleteButton                   matlab.ui.control.Button
        axisContainer                  matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        
        % stuff related to GAMS setup
        GAMS_HELP_LINK = 'github.com/lucasrhode95/ADAMS';
        GAMS_PATH_VARIABLE = 'GAMSDIR';
        PATH_VARIABLE = 'PATH';
        
        % other stuff
        ADAMS_HELP_LINK = 'https://github.com/lucasrhode95/ADAMS/blob/master/USER_MANUAL.pdf';
        VERSION = '0.1.1-beta';
        BUILD_DATE = ''; % calculated at deploy-time
        
        % process dialog used when running the model
	progressDialog = [];
 
        % template for the title bar
        figureNameTemplate = '%s - ADAMS'
        % current loaded file
        currentCase = 'Untitled';
        % current save status, true = saved, false = not saved
        saveStatus = true;
        
        % size of the auto-reflow
        myOnePanelWidth = 900;
        myTwoPanelWidth = 1024;
        
        % size of the UI blocks
        rowHeight = 150;
        colWidth  = 150;
        figureHeight = 750;
        leftPanelWidth = 300; % I really don't know why, but in my computer I had to restart the app twice for changes here to take effect.
        rightPanelWidth = 425;
        
        % line break column count
        % and column of the MGCC
        % Center: mgccCol = (app.maxCols+1)/2;
        maxCols = 5;
        mgccCol = 3;
        
        % List with all the elements added to the simulation
        % rows = MGElement data (first row is always MGCC data)
        % cols = 1: uiElement, 2:mgElement
        elementList = [{}];
        
        % List with all the instantiated UI buses
        busList = [{}];
        
        % Things to try and add a little thread-safe mechanism to the
        % MGElement adder functionality (don't think it works anyway)
        curRow = 0;
        curCol = 0;
        isProcessing = false;
        
        % UI selection control
        selectedRow = 0;
        selectedCol = 0;
        
        % List of all currently displayed input objects
        currentInputFields = [];
        
        % Last used directory
        lastDir = '';
        
        % TSChart instance, just to make sure there's only one.
        tsChartApp TSChart;
    end
    
    methods (Access = private)
    
        function imagePath = getImagePath(~, mgElement)
            
            switch mgElement.getClassName()
                case 'BatteryHan'
                    imagePath = 'battery_han.png';
                case 'BatteryLinear'
                    imagePath = 'battery_linear.png';
                case 'DieselGeneratorQuadratic'
                    imagePath = 'diesel_gen.png';
                case 'LoadConstant'
                    imagePath = 'load_constant.png';
                case 'LoadSheddable'
                    imagePath = 'load_sheddable.png';
                case 'SolarPanelConstant'
                    imagePath = 'solar_panel.png';
                otherwise
                    imagePath = 'mg_element.png';
            end
        end
        
        function [uiElement, row, col] = buildNewElementUI(app, mgElement)
            app.uiBlocked(true);
            
            [elementCount, ~] = size(app.elementList);
            elementCount = elementCount - 1; % minus 1 because the first one is always the MGCC controller
            
            app.curRow = floor(elementCount/app.maxCols + 1);
            app.curCol = rem(elementCount, app.maxCols)+1;
            row = app.curRow;
            col = app.curCol;
            if col == 1
                app.buildNewRowUI(2*row-1);
            end
            
            uiElement = uiimage(app.elementContainer);
            uiElement.ScaleMethod = 'scaledown';
            uiElement.Layout.Row = 2*row;
            uiElement.Layout.Column = col;
            uiElement.ImageSource = app.getImagePath(mgElement);
            uiElement.VerticalAlignment = 'top';
            uiElement.ImageClickedFcn = createCallbackFcn(app, @uiElementClicked, true);
            
            
            if col == 1
                app.updateViewSize();
            end
            app.uiBlocked(false);
        end
        
        function mgccTemp = buildNewMgccUI(app, row, mgcc)
            if nargin < 3
                mgcc = microgrid_model.CentralController();
            end
            
            mgccTemp = uiimage(app.elementContainer);
            mgccTemp.ScaleMethod = 'stretch';
            mgccTemp.Layout.Row = row;
            mgccTemp.Layout.Column = app.mgccCol;
            mgccTemp.VerticalAlignment = 'bottom';
            
            if row == 1
                mgccTemp.ImageSource = 'mgcc.png';
                mgccTemp.ImageClickedFcn = createCallbackFcn(app, @uiElementClicked, true);
                app.addMGElement(mgccTemp, mgcc);
            else
                mgccTemp.ImageSource = 'vert_dots.png';
            end
        end
    
        function buildNewRowUI(app, row)
            for i = 1:app.maxCols                
                if i == app.mgccCol
                    if row ~= 1
                       newBus = app.buildNewMgccUI(row);
                    else
                        newBus = [];
                    end
                else
                    newBus = uiimage(app.elementContainer);
                    newBus.ScaleMethod = 'stretch';
                    newBus.Layout.Row = row;
                    newBus.Layout.Column = i;
                    newBus.ImageSource = 'bus.png';
                    newBus.VerticalAlignment = 'bottom';
                end
                
                if ~isempty(newBus)
                    app.busList = [app.busList; {newBus}];
                end
            end
        end
    
        function uiBlocked(app, flag)
            app.isProcessing = flag;
            
            if flag
                flag = 'off';
                app.SimulateButton.Enable = 'off';
                app.ViewresultsButton.Enable = 'off';
            else
                flag = 'on';
                figure(app.UIFigure);
                app.checkIsReady();
            end
            
            for i = 1:length(app.currentInputFields)
                try % ignores errors
                    app.currentInputFields{i}.Enable = flag;
                catch
                end
            end
            
            app.FileMenu.Enable = flag;
            app.HelpMenu.Enable = flag;
            app.DeleteButton.Enable = flag;
            app.BatteryHansmodelButton.Enable = flag;
            app.BatteryLinearmodelButton.Enable = flag;
            app.DieselGenQuadraticmodelButton.Enable = flag;
            app.LoadFixedButton.Enable = flag;
            app.LoadSheddableButton.Enable = flag;
            app.SolarPanelTimeseriesmodelButton.Enable = flag;
            
        end
    
        function mgcc = getMgcc(app)
            mgcc = app.elementList{1, 2};
        end
    
        function [uiElement, mgElement, i] = getMGElement(app, row, col)
            [rowCount, ~] = size(app.elementList);
            for i = 1:rowCount
                uiElement = app.elementList{i, 1};
                mgElement = app.elementList{i, 2};
                
                if uiElement.Layout.Row == row && uiElement.Layout.Column == col
                    return;
                end
            end
            
            % in case nothing has been found
            uiElement = util.TypesUtil.empty;
            mgElement = util.TypesUtil.empty;
            i = util.TypesUtil.empty;
        end
    
        function addMGElement(app, uiElement, mgElement, add2MGCC)
            if nargin < 4
                add2MGCC = true;
            else
                util.TypesUtil.mustBeLogical(add2MGCC);
            end
            
            if ~isempty(app.elementList) && add2MGCC
                % Informs the Central controller about the new MGElement
                app.getMgcc().addMGElement(mgElement);
            end
            
            app.elementList = [app.elementList; {uiElement}, {mgElement}];
        end
    
        function inputList = getBatteryHanInputs(~)
            inputList = ...
                {
                 'checkbx'  , 'active'                  , 'Active'                      , {}
                 'integer'  , 'quantity'                , 'Quantity'                    , {};                   
                 'numeric'  , 'capacity'                , 'Capacity'                    , {'ValueDisplayFormat', '%0.4g kWh'}
                 'numeric'  , 'totalPrice'              , 'Total price'                 , {'ValueDisplayFormat', '%0.2f $'}
                 'percent'  , 'socMax'                  , 'SOC - maximum'               , {}
                 'percent'  , 'socMin'                  , 'SOC - minimum '              , {}
                 'percent'  , 'socIni'                  , 'SOC - initial'               , {}
                 'percent'  , 'socFinLo'                , 'SOC - minimum remaining'     , {}
                 'percent'  , 'socFinUp'                , 'SOC - maximum remaining'     , {}
                 'numeric'  , 'maxDischRate'            , 'Maximum rating - discharge'  , {'ValueDisplayFormat', '%0.4g kW'}
                 'numeric'  , 'maxChargRate'            , 'Maximum rating - recharge'   , {'ValueDisplayFormat', '%0.4g kW'}
                 'percent'  , 'dischEffi'               , 'Efficiency - discharge'      , {}
                 'percent'  , 'chargEffi'               , 'Efficiency - efficiency'     , {}
                 'percent'  , 'selfDisch'               , 'Self discharge rate'         , {}
                 'numeric'  , 'a0'                      , 'Wear parameter - A0'         , {'ValueDisplayFormat', '%0.4g'}
                 'numeric'  , 'a1'                      , 'Wear parameter - A1'         , {'ValueDisplayFormat', '%0.4g'}
                 'integer'  , 'linearizationPointsDod'  , 'Linearization points (DOD)'  , {}
                 'integer'  , 'linearizationPointsSoc'  , 'Linearization points (SOC)'  , {}
                 };
        end
    
        function inputList = getBatteryLinearInputs(~)
            inputList = ...
                {
                 'checkbx'  , 'active'                  , 'Active'                      , {}
                 'integer'  , 'quantity'                , 'Quantity'                    , {};                   
                 'numeric'  , 'capacity'                , 'Capacity'                    , {'ValueDisplayFormat', '%0.4g kWh'}
                 'percent'  , 'socMax'                  , 'SOC - maximum'               , {}
                 'percent'  , 'socMin'                  , 'SOC - minimum '              , {}
                 'percent'  , 'socIni'                  , 'SOC - initial'               , {}
                 'percent'  , 'socFinLo'                , 'SOC - minimum remaining'     , {}
                 'percent'  , 'socFinUp'                , 'SOC - maximum remaining'     , {}
                 'numeric'  , 'maxDischRate'            , 'Maximum rating - discharge'  , {'ValueDisplayFormat', '%0.4g kW'}
                 'numeric'  , 'maxChargRate'            , 'Maximum rating - recharge'   , {'ValueDisplayFormat', '%0.4g kW'}
                 'percent'  , 'dischEffi'               , 'Efficiency - discharge'      , {}
                 'percent'  , 'chargEffi'               , 'Efficiency - efficiency'     , {}
                 'percent'  , 'selfDisch'               , 'Self discharge rate'         , {}
                 'numeric'  , 'unitCost'                , 'Unit Energy Cost'            , {'ValueDisplayFormat'  , '%0.5g $/kWh'}
                 };
        end
    
        function inputList = getCentralControllerInputs(~)
            inputList = ...
                {
                 'integer'  , 'intendedHorizon'             , 'Hours of simulation'             , {'MultiplyBy', 1/60, 'ValueDisplayFormat', '%d h'}
                 'percent'  , 'relativeErrorTolerance'      , 'Tolerance (0% takes longer)'     , {}  
                 'numeric'  , 'hiredDemandOffPeak'          , 'Contracted power (off-peak)'     , {'ValueDisplayFormat', '%0.4g kW'}
                 'numeric'  , 'hiredDemandIntermediate'     , 'Contracted power (interm)'       , {'ValueDisplayFormat', '%0.4g kW'}
                 'numeric'  , 'hiredDemandPeak'             , 'Contracted power (peak)'         , {'ValueDisplayFormat', '%0.4g kW'}
                 'integer'  , 'step1stHalfHour'             , 'Time step of 1st half hour'      , {'ValueDisplayFormat', '%d min'}
                 'integer'  , 'step2ndHalfHour'             , 'Time step of 2nd half hour'      , {'ValueDisplayFormat', '%d min'}
                 'integer'  , 'stepDefault'                 , 'Default time step'               , {'ValueDisplayFormat', '%d min'}
                 'numeric'  , 't0'                          , 'Time offset (t0)'                , {'MultiplyBy', 1/60, 'ValueDisplayFormat', '%0.2g h'}
                 'numeric'  , 'fineWdayOffPeak'             , 'Exceeded demand penalty (off-peak)'       , {'ValueDisplayFormat', '%0.4g $/kW'}
                 'numeric'  , 'fineWdayIntermediate'        , 'Exceeded demand penalty (interm)'         , {'ValueDisplayFormat', '%0.4g $/kW'}
                 'numeric'  , 'fineWdayPeak'                , 'Exceeded demand penalty (peak)'           , {'ValueDisplayFormat', '%0.4g $/kW'}
                 'numeric'  , 'tariffPurcWdayOffPeak'       , 'Energy purchase - off-peak'      , {'ValueDisplayFormat', '%0.4g $/kWh'}
                 'numeric'  , 'tariffPurcWdayIntermediate'  , 'Energy purchase - intermediate'  , {'ValueDisplayFormat', '%0.4g $/kWh'}
                 'numeric'  , 'tariffPurcWdayPeak'          , 'Energy purchase - peak'          , {'ValueDisplayFormat', '%0.4g $/kWh'}
                 'numeric'  , 'tariffSellWdayOffPeak'       , 'Energy selling off-peak'         , {'ValueDisplayFormat', '%0.4g $/kWh'}
                 'numeric'  , 'tariffSellWdayIntermediate'  , 'Energy selling intermediate'     , {'ValueDisplayFormat', '%0.4g $/kWh'}
                 'numeric'  , 'tariffSellWdayPeak'          , 'Energy selling peak'             , {'ValueDisplayFormat', '%0.4g $/kWh'}
                };
        end
    
        function inputList = getDieselGenQuadInputs(~)
            inputList = ...
                {
                 'checkbx' , 'active'              , 'Active'                     , {}
                 'integer' , 'quantity'            , 'Quantity'                   , {}                     
                 'numeric' , 'fuelCost'            , 'Fuel Cost'                  , {'ValueDisplayFormat'   '%0.4g $/liter'}     
                 'numeric' , 'maxPower'            , 'Rating - nominal output'    , {'ValueDisplayFormat'   '%0.4g kW'}          
                 'percent' , 'minRelativePower'    , 'Rating - minimal load'      , {}                     
                 'numeric' , 'startupCost'         , 'Startup cost'               , {'ValueDisplayFormat'   '%0.4g $/startup'}   
                 'numeric' , 'a'                   , 'Fuel consumption model (a)' , {'ValueDisplayFormat'   '%0.4g liter/kW²'}  
                 'numeric' , 'b'                   , 'Fuel consumption model (b)' , {'ValueDisplayFormat'   '%0.4g liter/kW'}
                 'numeric' , 'c'                   , 'Fuel consumption model (c)' , {'ValueDisplayFormat'   '%0.4g liter/hour'}
                 'integer' , 'linearizationPoints' , 'Linearization points'       , {}
                 'checkbx' , 'initiallyOn'         , 'Initially on?'              , {}
                };
        end
    
        function inputList = getLoadSheddableInputs(~, mgElement, row, col)
            function importDemandProfile(app, event)
                if mgElement.importDemandProfile()
                    app.EditFieldValueChanged(event);
                end
                app.buildOptPane(row, col);
            end
            
            inputList = ...
                {
                 'checkbx' , 'active'              , 'Active'                 , {}
                 'numeric' , 'sheddingOpportunity' , 'Shedding cost per kWh'  , {'ValueDisplayFormat'   '%0.4g $/kWh'}
                 'numeric' , 'sheddingTariff'      , 'Shedding cost per hour' , {'ValueDisplayFormat'   '%0.4g $/hour'}
                 'actnbtn' , @importDemandProfile  , 'Load demand profile'    , {}
                 'guiaxes' , 'getOriginalProfile'  , 'Demand Profile'         , {'Xaxis', 'getOriginalTimeArray', 'Xlabel', 'Minute of day', 'Ylabel', 'Demand (kW)'}
                };
         end
            
        function inputList = getLoadConstantInputs(~, mgElement, row, col)
            function importDemandProfile(app, ~)
                if mgElement.importDemandProfile()
                    app.EditFieldValueChanged([]);
                end
                app.buildOptPane(row, col);
            end
            
            inputList = ...
                {
                 'checkbx' , 'active'             , 'Active'              , {}
                 'actnbtn' , @importDemandProfile , 'Load demand profile' , {}
                 'guiaxes' , 'getOriginalProfile'   , 'Demand Profile'      , {'Xaxis', 'getOriginalTimeArray', 'Xlabel', 'Minute of day', 'Ylabel', 'Demand (kW)'}
                };
        end
    
        function inputList = getSolarPanelConstantInputs(~, mgElement, row, col)
            function importGenerationProfile(app, ~)
                mgElement.importGenerationProfile();
                app.buildOptPane(row, col);
            end
            
            inputList = ...
                {
                 'checkbx' , 'active'                 , 'Active'                  , {}
                 'actnbtn' , @importGenerationProfile , 'Load generation profile' , {}
                 'guiaxes' , 'getOriginalProfile'   , 'Generation Profile'      , {'Xaxis', 'getOriginalTimeArray', 'Xlabel', 'Minute of day', 'Ylabel', 'Power output (kWh)'}
                };
        end
    
        function [inputList, mgElement, index] = getObjectData(app, row, col)
            
            [~, mgElement, index] = app.getMGElement(row, col);
            
            if isempty(mgElement)
                inputList = util.TypesUtil.emptyCellArray;
                return;
            end
            
            switch mgElement.getClassName()
                case 'BatteryHan'
                    inputList = app.getBatteryHanInputs();
                case 'BatteryLinear'
                    inputList = app.getBatteryLinearInputs();
                case 'CentralController'
                    inputList = app.getCentralControllerInputs();
                case 'DieselGeneratorQuadratic'
                    inputList = app.getDieselGenQuadInputs();
                case 'LoadConstant'
                    inputList = app.getLoadConstantInputs(mgElement, row, col);
                case 'LoadSheddable'
                    inputList = app.getLoadSheddableInputs(mgElement, row, col);
                case 'SolarPanelConstant'
                    inputList = app.getSolarPanelConstantInputs(mgElement, row, col);
                otherwise
                    error('Class "%s" is not recognized', mgElement.getClassName());
            end
        end
    
        function clearOptPane(app)
             % iterates over everything that is currently on the UI
            [inputCount, ~] = size(app.currentInputFields);
            
            for i = 1:inputCount
                % this will remove the field object from view
                delete(app.currentInputFields{i, 1});
                
                % this will remove the label object from view
                delete(app.currentInputFields{i, 2});
            end
            
            app.currentInputFields = util.TypesUtil.emptyCellArray;
        end
    
        function saveOptPane(app, row, col)
            if row < 1 || col < 1
                return;
            end
            
            % retrieves the MGElement object
            [~, mgElement, ~] = app.getObjectData(row, col);
            if isempty(mgElement)
                return;
            end
            
            % iterates over everything that is currently on the UI
            [inputCount, ~] = size(app.currentInputFields);
            for i = 1:inputCount
                % the first column holds the handle of the UI element
                uiElement = app.currentInputFields{i, 1};
                
                if isa(uiElement, 'matlab.ui.control.Button') || isa(uiElement, 'matlab.ui.control.UIAxes')
                    continue;
                end
                
                % the third column holds the property name
                property = app.currentInputFields{i, 3};
                
                % the fourth ird column holds the isPercentage metadata
                isPercentage = app.currentInputFields{i, 4};
                
                % the fifth column holds the isInteger metadata
                isInteger = app.currentInputFields{i, 5};
                
                % column #6 is the multiplier
                multiplier = app.currentInputFields{i, 6};
                
                % passes the input field value to the MGElement object
                elementValue = uiElement.Value/multiplier;
                if isInteger
                    elementValue = round(elementValue);
                end
                if isPercentage
                    elementValue = elementValue/100;
                end

                if isa(uiElement, 'matlab.ui.control.CheckBox')
                    elementValue = logical(elementValue);
                end
                mgElement.(property) = elementValue;
            end
        end
    
        function buildOptPane(app, row, col)
            callback = [];
            % Create callback
            function dummyCallback(app, event)
                try
                    app.uiBlocked(true);
                    callback(app, event);
                    app.uiBlocked(false);
                catch e                
                    app.uiBlocked(false);
                    disp(getReport(e));
                    uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
                end
            end
            
            % updates the model that was selected up until before now
            app.saveOptPane(app.selectedRow, app.selectedCol);
            
            % clears everything
            app.clearOptPane();
                
            % restores the view from possible previous axes plots.
            app.outterGrid.RowHeight = {'1x', '0x'};
            
            % updates the control variables
            app.selectedRow = row;
            app.selectedCol = col;
            
            % if the currently selected object is the MGCC, disables the delete button.
            if row == 1 && col == app.mgccCol
                app.DeleteButton.Enable  = 'off';
                app.DeleteButton.Visible = 'off';
            else
                app.DeleteButton.Enable  = 'on';
                app.DeleteButton.Visible = 'on';
            end
            
            % retrieves the clicked object
            [inputList, mgElement, ~] = app.getObjectData(row, col);
            
            % update right panel title
            app.RightPanel.Title = sprintf('Properties of element #%d (%s)', mgElement.getId(), mgElement.getClassName());
            
            % loops throgh the input list and creates all input fields
            % necessary. In spite of the word "inputs", we can have some
            % output (only plots for now) for the user to preview the
            % result of their input
            [inputCount, ~] = size(inputList);
            inputRow = 0;
            axisRow = 0;
            for i = 1:inputCount
                inputType  = inputList{i, 1};
                property   = inputList{i, 2};
                labelTxt   = inputList{i, 3};
                
                if length(inputList(i, :)) >= 4
                    extraOpt   = inputList{i, 4};
                else
                    extraOpt = util.TypesUtil.emptyCellArray;
                end
                
                % retrieves the field value (unless it's a callback function)
                if ~strcmp(inputType, 'actnbtn')
                    fieldValue = mgElement.(property);
                else
                    fieldValue = property;
                end
                
                % process the Multiplier option and removes it from the array
                % this option is used when a value entered by the user
                % needs to be multiplied by a number before saving it.
                % Conversely, when the value is loaded into the field, it
                % needs to be divided by the same value.
                if ~isempty(extraOpt) && strcmp(extraOpt{1}, 'MultiplyBy')
                    multiplier = extraOpt{2};
                    fieldValue = fieldValue*multiplier;
                    extraOpt(2) = [];
                    extraOpt(1) = [];
                else
                    multiplier = 1;
                end
                
                % data type pre processing
                isInteger = false;
                isPercentage = false;
                switch inputType
                    case 'integer'
                        inputType = 'numeric';

                        % if there's no value disp format, adds it
                        hasFormat = strfind(util.TypesUtil.num2CharCell(extraOpt), 'ValueDisplayFormat');
                        if isempty([hasFormat{:}])
                            extraOpt = [extraOpt, {'ValueDisplayFormat', '%d'}];
                        end
                        
                        fieldValue = round(fieldValue);
                        isInteger = true;
                    case 'percent'
                        inputType = 'numeric';
                        
                        % if there's no value disp format, adds it
                        hasFormat = strfind(extraOpt, 'ValueDisplayFormat');
                        if isempty([hasFormat{:}])
                            extraOpt = [extraOpt, {'ValueDisplayFormat', '%0.2f%%'}];
                        end
                        
                        fieldValue = 100*fieldValue;
                        isPercentage = true;
                end
                
                switch inputType
                    case 'guiaxes'
                        app.outterGrid.RowHeight = {'1x', '1x'};
                        axisRow = axisRow + 1;
                        
                        % Create UIAxes
                        newField = uiaxes(app.axisContainer);
                        title(newField, labelTxt)
                        newField.Layout.Row = axisRow;
                        newField.Layout.Column = 1;
                        newField.XGrid = 'on';
                        newField.YGrid = 'on';
                        newField.XMinorGrid = 'on';
                        newField.YMinorGrid = 'on';
                        
                        % read optional input and updates the UI
                        iterations2skip = 0;
                        xAxis = 1:length(fieldValue);
                        Xlabel = 'x';
                        Ylabel = 'y';
                        for j = 1:length(extraOpt)
                            if iterations2skip > 0
                                iterations2skip = iterations2skip - 1;
                                continue;
                            end
                            
                            switch extraOpt{j}
                                case 'Xaxis'
                                    xAxis = mgElement.(extraOpt{j+1});
                                    iterations2skip = 1;
                                case 'Xlabel'
                                    Xlabel = extraOpt{j+1};
                                    iterations2skip = 1;
                                case 'Ylabel'
                                    Ylabel = extraOpt{j+1};
                                    iterations2skip = 1;
                            end
                        end
                        xlabel(newField, Xlabel);
                        ylabel(newField, Ylabel);
                        stairs(newField, xAxis, fieldValue, '-');
                        
                        % Create dummy Label
                        newLabel = uilabel(app.axisContainer);
                        newLabel.Layout.Row = axisRow;
                        newLabel.Layout.Column = 1;
                        newLabel.Text = '';
                    case 'actnbtn'
                        inputRow = inputRow + 1;
                        callback = property;
                        
                        % Create dummy Label
                        newLabel = uilabel(app.inputContainer);
                        newLabel.Layout.Row = inputRow;
                        newLabel.Layout.Column = 1;
                        newLabel.Text = '';
                        
                        % Create Button
                        newField = uibutton(app.inputContainer, 'push');
                        newField.ButtonPushedFcn = createCallbackFcn(app, @dummyCallback, true);
                        newField.BusyAction = 'cancel';
                        newField.Interruptible = 'off';
                        newField.Layout.Row = inputRow;
                        newField.Layout.Column = 2;
                        newField.Text = labelTxt;
                    case 'checkbx'
                        inputRow = inputRow + 1;
                        
                        % Create dumy Label
                        newLabel = uilabel(app.inputContainer);
                        newLabel.Layout.Row = inputRow;
                        newLabel.Layout.Column = 1;
                        newLabel.Text = '';
                        
                        % Create Checkbox
                        newField = uicheckbox(app.inputContainer, extraOpt{:});
                        newField.Text = labelTxt;
                        newField.Layout.Row = inputRow;
                        newField.Layout.Column = 2;
                        newField.Value = fieldValue;
                        
                        % registers value changed callback
                        newField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
                    otherwise
                        inputRow = inputRow + 1;
                        
                        % Create Label
                        newLabel = uilabel(app.inputContainer);
                        newLabel.HorizontalAlignment = 'left';
                        newLabel.Layout.Row = inputRow;
                        newLabel.Layout.Column = 1;
                        newLabel.Text = labelTxt;
            
                        % Create Edit Field
                        newField = uieditfield(app.inputContainer, inputType, extraOpt{:});
                        newField.Layout.Row = inputRow;
                        newField.Layout.Column = 2;
                        newField.Value = fieldValue;
                        
                        % registers value changed callback
                        newField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
                end
                
                % Stores the result for future reference
                app.currentInputFields = [app.currentInputFields; {newField, newLabel, property, isPercentage, isInteger, multiplier}];
            end
            
            % Replaces and resizes the delete Button
            if strcmp(app.DeleteButton.Visible, 'on')
                inputRow = inputRow+1;
                app.DeleteButton.Layout.Row = inputRow;
            end
            app.inputContainer.RowHeight = repmat({30}, 1, inputRow);
        end
    
        function updateIconsPosition(app)
            [elementCount, ~] = size(app.elementList);
            
            % variable initialization
            row = 1;
            for i = 2:elementCount
                row = floor((i-2)/app.maxCols + 1); % calculates at which row the element should appear
                col = rem((i-2), app.maxCols) + 1; % calculates at which column the element should appear
                
                app.elementList{i, 1}.Layout.Row = 2*row;
                app.elementList{i, 1}.Layout.Column = col;
            end
            
            % deletes unnecessary buses
            [elementCount, ~] = size(app.busList);
            for i = elementCount:-1:1
                if app.busList{i}.Layout.Row > 2*row-1
                    delete(app.busList{i});
                    app.busList(i) = [];
                end
            end
        end
        
        function clearUi(app)
            [rowCount, ~] = size(app.elementList);
            for i = 1:rowCount
                delete(app.elementList{i, 1});
                delete(app.elementList{i, 2});
            end
            app.elementList = util.TypesUtil.emptyCellArray;
        end
        
        function result = checkIsReady(app)            
            
            try
                result = ~isempty(app.getMgcc().getMicrogrid().getMGElements(true));
            catch e
                disp('Error while checking if model is ready for execution:');
                disp(getReport(e));
                
                result = false;
            end
            
            % if there're no elements, blocks the simulate button
            if result
                app.SimulateButton.Enable = 'on';
                
                if app.getMgcc().getHasBeenRun()
                    app.ViewresultsButton.Enable = 'on';
                end
            else
                app.SimulateButton.Enable = 'off';
                app.ViewresultsButton.Enable = 'off';
            end
        end
        
        function updateViewSize(app)
        % updates the size of each UI block
            
            % updates the central panel layout            
            app.elementContainer.ColumnWidth = repmat({app.colWidth}, 1, app.maxCols);
            app.elementContainer.RowHeight   = repmat({app.rowHeight}, 1, length(app.elementContainer.RowHeight));
            app.macrogridRow.ColumnWidth     = repmat({app.colWidth}, 1, app.maxCols);
            app.macrogridImage.Layout.Column = app.mgccCol;
        
            cols = repmat({app.colWidth}, 1, app.maxCols);
            
            app.GridLayout2.ColumnWidth  = {'1x', app.colWidth*app.maxCols, '1x'};
            app.GridLayout2.RowHeight    = [{app.rowHeight}, app.rowHeight*length(app.elementContainer.RowHeight)];
            
            app.macrogridRow.ColumnWidth     = cols;
            app.elementContainer.ColumnWidth = cols;
            app.elementContainer.RowHeight   = repmat({app.rowHeight}, 1, length(app.elementContainer.RowHeight));
        end
        
        function myUpdateAppLayout(app, ~)            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.myOnePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {app.figureHeight, app.figureHeight, app.figureHeight};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.myOnePanelWidth && currentFigureWidth <= app.myTwoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {app.figureHeight, app.figureHeight};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {app.leftPanelWidth, '1x', app.rightPanelWidth};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function updateFigureTitle(app, fileName)
            if nargin < 2
                fileName = app.currentCase;
            else
                app.currentCase = fileName; % updates the current case file name
            end
            
            fileName = util.FilesUtil.getFileName(fileName, false); % retrieves only the file name, no dir, no file extension.
            
            if util.StringsUtil.endsWith(fileName, '.mat')
                fileName = char(fileName);
                fileName = fileName(1:end-4);
            end
            
            if ~app.saveStatus
                fileName = strcat(fileName, '*');
            end
            
            app.UIFigure.Name = sprintf(app.figureNameTemplate, fileName); % updates the figure title
        end
        
        function result = handleGamsEnviromentProblem(app, status, msg)
        % Warns the user that there's a problem with their GAMS enviroment
        % and tries to fix it together <3
            import util.FilesUtil
            import util.CommonsUtil
            import util.StringsUtil
            import gams.GAMSModel
            
            CommonsUtil.log('GAMS problem detected:\n');
            CommonsUtil.log('\tStatus=%d\n', status);
            CommonsUtil.log('\tMessage="%s"\n', msg);
            
            % first trial: GAMSDIR variable
            gamsDir = getenv(app.GAMS_PATH_VARIABLE);
            if ~isempty(gamsDir)
                setenv(app.PATH_VARIABLE, [getenv(app.PATH_VARIABLE) ';' gamsDir ';']);
                if gams.GAMSModel.checkGams() == gams.GAMSModel.STATUS_OK
                    result = 0;
                    CommonsUtil.log('Solution: used "%s" variable instead.\n', app.GAMS_PATH_VARIABLE);
                    return;
                else
                    setenv(app.PATH_VARIABLE, strrep(getenv(app.PATH_VARIABLE), [';' gamsDir ';'], ''));
                end
            else
                CommonsUtil.log('"%s" variable empty.\n', app.GAMS_PATH_VARIABLE);
            end
            
            % second trial: ask for user's help
            selection = uiconfirm(app.UIFigure, {'ADAMS is having trouble communicating with GAMS.';
                '';
                'Let''s try to fix it.';
                'Select the folder where gams.exe is installed.'}, 'GAMS problem', ...
                'Icon','warning');
            
            % If we return a negative number, the app closes
            if strcmp(selection, 'Cancel')
                result = -1;
                return;
            end
            
            % Try until it works or the user give up
            while true
                [~, gamsDir] = util.FilesUtil.uiGetFile('gams.exe');
                if isempty(gamsDir); result = -1; return; end
                
                setenv(app.PATH_VARIABLE, [getenv(app.PATH_VARIABLE) ';' gamsDir]);
                
                % if it works, alright
                [status, msg] = gams.GAMSModel.checkGams();
                if status == gams.GAMSModel.STATUS_OK
                    result = 0;
                    return;
                % if not, reset enviroment variables and try again
                % (no need to reset MATLAB's path because it's not
                % permanent)
                else
                    CommonsUtil.log('Path "%s" didn''t work\n', gamsDir);
                    CommonsUtil.log('\tStatus=%d', status);
                    CommonsUtil.log('\tMessage=%s', msg);
                    
                    setenv(app.PATH_VARIABLE, strrep(getenv(app.PATH_VARIABLE), [';' gamsDir], ''));
                    
                    selection = uiconfirm(app.UIFigure, {'The folder you selected didn''t work :/';
                        ''; 'Try again?'}, ...
                        'It didn''t work...', ...
                        'Icon','warning');
                    
                    if strcmp(selection, 'Cancel')
                        result = -1;
                        return;
                    else
                        continue;
                    end
                end
            end
        end
        
        function result = checkGams(app)
        % Routine that checks if GAMS is correctly installed and accessible
        % from MATLAB
            import util.StringsUtil
            
            % Checks if GAMS is ok
            [status, msg] = gams.GAMSModel.checkGams();
            result = status == gams.GAMSModel.STATUS_OK;
            
            % if not, asks for user's help
            if ~result
                result = app.handleGamsEnviromentProblem(status, msg) >= 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import util.FilesUtil
            import util.CommonsUtil
            import gams.GAMSModel
            
            try
                clc;
                if isdeployed
                    app.BUILD_DATE = builddate;
                    cd(tempdir());
                    movegui(app.UIFigure, 'center');
                end
                
                FilesUtil.cacheStatus(true);
                
                if ~app.checkGams()
                    app.delete();
                    return;
                end
                
                % logs the beginning of the initialization process
                CommonsUtil.log('Building GUI...\n');
                h = uiprogressdlg(app.UIFigure, 'Title', 'Building GUI...', 'Cancelable', 'off');
                
                % disables GUI temporarily
                app.uiBlocked(true);
                
                h.Value = 0.1;
                h.Message = 'Setting up UIfigure';
                
                % App name
                app.saveStatus = true;
                app.updateFigureTitle('Untitled');
                
                h.Value = 0.15;
                
                % resets resize callback
                app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
    
                % sets the PGCEE Framework to debug mode
                h.Value = 0.2;
                h.Message = 'Setting up the emulator';
                microgrid_model.MGElement.developmentMode(true);
                microgrid_model.MGElement.warningsOn(true);
    
                % builds and shows the options for the central controller
                h.Value = 0.35;
                h.Message = 'Setting up the Central Controller';
                app.buildNewMgccUI(1);
                app.buildOptPane(1, app.mgccCol);
                
                % updates the macrogrid image
                h.Value = 0.45;
                h.Message = 'Setting up command buttons';
                app.macrogridImageClicked([]);
                app.BatteryHansmodelButton.Icon = 'battery_han.png';
                app.BatteryLinearmodelButton.Icon = 'battery_linear.png';
                h.Value = 0.55;
                app.DieselGenQuadraticmodelButton.Icon = 'diesel_gen.png';
                app.LoadFixedButton.Icon = 'load_constant.png';
                app.SolarPanelTimeseriesmodelButton.Icon = 'solar_panel.png';
                app.LoadSheddableButton.Icon = 'load_sheddable.png';
                h.Value = 0.65;
                app.SimulateButton.Icon = 'play_icon.png';
                app.ViewresultsButton.Icon = 'tschart_icon.png';
                app.DeleteButton.Icon = 'delete_icon.png';
                h.Value = 0.8;
                
                % sizes the figure properly
                h.Value = 0.85;
                h.Message = 'Resizing interface';
                figWidth = app.maxCols*app.colWidth + app.leftPanelWidth + app.rightPanelWidth + 50;
                app.UIFigure.Position = [100 100 figWidth app.figureHeight];
                app.updateViewSize();
                h.Value = 0.95;
                
                % centering
                h.Message = 'Centering...';
                h.Value = 0.99;
                movegui(app.UIFigure, 'center');
                
                % reenables GUI
                app.UIFigure.Visible = 'on';
                app.uiBlocked(false);
                       
                h.Value = 1;
                h.Message = 'Done!';
                close(h);
                CommonsUtil.log('GUI built!\n');
            catch e
                disp(getReport(e));
                msgbox(sprintf('An error occurred at startup: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {475, 475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x', 191};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: BatteryHansmodelButton, 
        % BatteryLinearmodelButton, DieselGenQuadraticmodelButton, 
        % LoadFixedButton, LoadSheddableButton, 
        % SolarPanelTimeseriesmodelButton
        function AddElementButtonPushed(app, event)

            try
                util.CommonsUtil.log('Adding new microgrid element...\n');
                
                % builds new MGElement object
                buttonTxt = event.Source.Text;
                switch buttonTxt
                    case '+ Battery (Han''s model)'
                        mgElement = microgrid_model.BatteryHan();
                    case '+ Battery (Linear model)'
                        mgElement = microgrid_model.BatteryLinear();
                    case '+ Diesel Gen (Quadratic model)'
                        mgElement = microgrid_model.DieselGeneratorQuadratic();
                    case '+ Load (Fixed)'
                        mgElement = microgrid_model.LoadConstant();
                    case '+ Load (Sheddable)'
                        mgElement = microgrid_model.LoadSheddable();
                    case '+ Solar Panel (Time series model)'
                        mgElement = microgrid_model.SolarPanelConstant();
                    otherwise
                        error('Button "%s" not recognized.', buttonTxt);
                end
                
                % builds new display icon
                [uiElement, row, col] = app.buildNewElementUI(mgElement);
                
                % adds the result to  the result list
                app.addMGElement(uiElement, mgElement);
                
                % updates the option pane to show the newly added device options
                app.buildOptPane(2*row, col);
                
                % updates the simulate button status
                app.checkIsReady();
                
                util.CommonsUtil.log('Element added!\n');
            catch e
                disp(getReport(e));
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Callback function
        function uiElementClicked(app, event)
            util.CommonsUtil.log('Building option pane... ');
            
            app.uiBlocked(true);
            
            row = event.Source.Layout.Row;
            col = event.Source.Layout.Column;
            app.buildOptPane(row, col);
            
            app.uiBlocked(false);
            
            util.CommonsUtil.log('Done!\n');
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
        
            % blocks the UI
            app.uiBlocked(true);
            
            % finds the element in the list
            [uiElement, mgElement, index] = app.getMGElement(app.selectedRow, app.selectedCol);
            if isempty(index)
                return;
            end
            % checks if its not the CentralController
            if (index == 1)
                error('CentralController cannot be removed.');
            end
            
            % Removes it from the CentralController list
            app.getMgcc().removeMGElement(mgElement);
            
            % removes it from the App's list
            app.elementList(index, :) = [];
            delete(uiElement);
            delete(mgElement);
            
            % replaces everyone
            app.updateIconsPosition();
            
            % inhibits buildOptPane from updating a deleted handle
            app.selectedRow = 0;
            app.selectedCol = 0;

            % changes focus to last element of the list
            lastRow = app.elementList{end, 1}.Layout.Row;
            lastCol = app.elementList{end, 1}.Layout.Column;
            app.buildOptPane(lastRow, lastCol);
            
            % updates the simulate button status
            app.checkIsReady();
            
            % unblocks the UI
            app.uiBlocked(false);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                app.uiBlocked(true);
                
                
                % displays a progress bar
                this.progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'GAMS is now solving... (this can take a while)', 'Indeterminate', 'on');
                
                % saves whatever is currently on the screen
                app.saveOptPane(app.selectedRow, app.selectedCol);
                
                % actually runs the model
                app.getMgcc().run();
                
                % when it is done, unblocks the UI
                app.uiBlocked(false);
                
                % closes the progress bar
                try % surrounded in a try-catch statement in case the user has manually closed the uiprogressdlg
                    close(this.progressDialog);
                catch
                end
                
                % sends the results to TSChart
                try app.tsChartApp.delete; catch ; end
                app.tsChartApp = TSChart(app.getMgcc());
            catch e
                % closes any progress dialogs left open (for example when an execution abruptly stops)
                try
                    close(this.progressDialog)
                catch
                end
                disp(getReport(e));
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
                app.uiBlocked(false);
            end
        end

        % Image clicked function: macrogridImage
        function macrogridImageClicked(app, event)
            % toggles the isIsland flag
            mgccUi    = app.elementList{1, 1};
            microgrid = app.getMgcc().getMicrogrid();
            if ~isempty(event)
                microgrid.isIsland = ~microgrid.isIsland;
                if microgrid.isIsland
                    uialert(app.UIFigure, sprintf('You switched to island mode, here are some warnings:\n- In this mode the use of fixed loads is discouraged.\n- If your get all-zero results, is likely that the model failed to converge.'), 'Warning', 'Icon', 'warning');
                end
            end
            
            if microgrid.isIsland                
                app.macrogridImage.ImageSource = 'macrogrid_island.png';
                mgccUi.ImageSource = 'mgcc_island.png';
            else
                app.macrogridImage.ImageSource = 'macrogrid.png';
                mgccUi.ImageSource = 'mgcc.png';
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            try
                app.uiBlocked(true);
                
                app.saveOptPane(app.selectedRow, app.selectedCol);
                
                filePath = util.FilesUtil.uiPutFile(['ADAMS_Properties - ', util.CommonsUtil.getTimestamp(true), '.mat'], 'Export ADAMS properties');
                if isempty(filePath)
                    app.uiBlocked(false);
                    return;
                end
                
                % retrieves the data we need to save: the MGCC object, containing all the othe objects.
                % and the gdxFile, containing the results of the last simulation.
                mgcc = app.getMgcc();
                
                mgcc.save(filePath);
                
                % updates the figure title
                app.saveStatus = true;
                app.updateFigureTitle(filePath);
                
                app.uiBlocked(false);
            catch e
                app.uiBlocked(false);
                disp(getReport(e));
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            try
                util.CommonsUtil.log('Open menu selected.\n');
                app.uiBlocked(true);
                
                % user input (file path)
                filePath = util.FilesUtil.uiGetFile('*.mat', 'Import ADAMS properties');
                
                % if the user has cancelled the action, does nothing
                if isempty(filePath)
                    app.uiBlocked(false);
                    return;
                end
                
                % deletes the old mgcc
                util.CommonsUtil.log('Clearing old data...\n');
                delete(app.getMgcc());
                util.CommonsUtil.log('Done!\n');
                
                % loads the mgcc object
                mgcc = microgrid_model.CentralController.load(filePath);
               
                % clears the UI and builds a new MGCC
                app.clearUi();         
                app.buildNewMgccUI(1, mgcc);
                
                % updates the option panel
                app.selectedRow = 0;
                app.selectedCol = 0;
                app.buildOptPane(1, app.mgccCol);
                
                % updates the macrogrid image
                app.macrogridImageClicked([]);
                
                % recreates every MGElement graphical representation
                mgElements = mgcc.getMicrogrid().getMGElements();
                for i = 1:length(mgElements)
                    uiElement = app.buildNewElementUI(mgElements{i});
                    app.addMGElement(uiElement, mgElements{i}, false);
                end
                
                % updates the Simulate button state
                app.checkIsReady();
                
                % updates everyone's position
                app.updateIconsPosition();
                
                % updates figure title
                app.saveStatus = true;
                app.updateFigureTitle(filePath);
                
                % reenables the UI
                app.uiBlocked(false);
                
                util.CommonsUtil.log('Case [%s] loaded.\n', filePath);
            catch e
                disp(getReport(e));
                app.uiBlocked(false);
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Button pushed function: ViewresultsButton
        function ViewresultsButtonPushed(app, event)
            try
                % sends the results to TSChart
                try app.tsChartApp.delete; catch e
                    disp(getreport(e)); end
                app.tsChartApp = TSChart(app.getMgcc());
            catch e
                getReport(e)
                app.uiBlocked(false);
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.saveStatus = false;
            app.updateFigureTitle();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            import util.FilesUtil
            
             try
                app.uiBlocked(true);
                
                % copies data from the GUI to the MGCC object
                app.saveOptPane(app.selectedRow, app.selectedCol);
                
                dir = util.FilesUtil.uiGetDir();
                
                % if user cancels the action, exits the method
                if isempty(dir); app.uiBlocked(false); return; end
                
                app.getMgcc().export(dir);
                app.uiBlocked(false);
             catch e
                app.uiBlocked(false);
                disp(getReport(e));
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
             end
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web(sprintf('%s', app.ADAMS_HELP_LINK));
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msg = 'ADAMS: a day-ahead microgrid simulator\n\n';
            msg = [msg, 'MATLAB version: %s\n'];
            msg = [msg, 'Version: %s (%s)'];
            msg = sprintf(msg, version(), app.VERSION, app.BUILD_DATE);
            uialert(app.UIFigure, msg, 'Info', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UIFigure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Tooltip = {''};
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Continue working on previous simulations'};
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Tooltip = {'Save this simulation'; ' including the results'};
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Tooltip = {'Export the simulation data as GAMS code'};
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Available elements';
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.BatteryHansmodelButton = uibutton(app.GridLayout3, 'push');
            app.BatteryHansmodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.BatteryHansmodelButton.Icon = 'battery_han.png';
            app.BatteryHansmodelButton.Text = '+ Battery (Han''s model)';
            app.BatteryLinearmodelButton = uibutton(app.GridLayout3, 'push');
            app.BatteryLinearmodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.BatteryLinearmodelButton.Icon = 'battery_linear.png';
            app.BatteryLinearmodelButton.Text = '+ Battery (Linear model)';
            app.DieselGenQuadraticmodelButton = uibutton(app.GridLayout3, 'push');
            app.DieselGenQuadraticmodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.DieselGenQuadraticmodelButton.Icon = 'diesel_gen.png';
            app.DieselGenQuadraticmodelButton.Text = '+ Diesel Gen (Quadratic model)';
            app.LoadFixedButton = uibutton(app.GridLayout3, 'push');
            app.LoadFixedButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.LoadFixedButton.Icon = 'load_constant.png';
            app.LoadFixedButton.Text = '+ Load (Fixed)';
            app.SolarPanelTimeseriesmodelButton = uibutton(app.GridLayout3, 'push');
            app.SolarPanelTimeseriesmodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.SolarPanelTimeseriesmodelButton.Icon = 'solar_panel.png';
            app.SolarPanelTimeseriesmodelButton.Text = '+ Solar Panel (Time series model)';
            app.LoadSheddableButton = uibutton(app.GridLayout3, 'push');
            app.LoadSheddableButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.LoadSheddableButton.Icon = 'load_sheddable.png';
            app.LoadSheddableButton.Text = '+ Load (Sheddable)';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SimulateButton = uibutton(app.GridLayout5, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = 'play_icon.png';
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate!';
            app.ViewresultsButton = uibutton(app.GridLayout5, 'push');
            app.ViewresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewresultsButtonPushed, true);
            app.ViewresultsButton.Icon = 'tschart_icon.png';
            app.ViewresultsButton.Enable = 'off';
            app.ViewresultsButton.Text = {'View'; 'results'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Microgrid';
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.macrogridRow = uigridlayout(app.GridLayout2);
            app.macrogridImage = uiimage(app.macrogridRow);
            app.macrogridImage.ImageClickedFcn = createCallbackFcn(app, @macrogridImageClicked, true);
            app.macrogridImage.ImageSource = 'macrogrid.png';
            app.elementContainer = uigridlayout(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Properties';
            app.outterGrid = uigridlayout(app.RightPanel);
            app.inputContainer = uigridlayout(app.outterGrid);
            app.DeleteButton = uibutton(app.inputContainer, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = 'delete_icon.png';
            app.DeleteButton.Text = 'Delete';
            app.axisContainer = uigridlayout(app.outterGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADAMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end