classdef TSChart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        GridLayout2    matlab.ui.container.GridLayout
        ListBox        matlab.ui.control.ListBox
        GridLayout5    matlab.ui.container.GridLayout
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        DeviceLabel    matlab.ui.control.Label
        GridLayout3    matlab.ui.container.GridLayout
        GridLayout4    matlab.ui.container.GridLayout
        HoldButton     matlab.ui.control.StateButton
        ExportButton   matlab.ui.control.Button
        ClearButton    matlab.ui.control.Button
        DetachButton   matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        GridLayout6    matlab.ui.container.GridLayout
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        RawdataLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        % Time array of the simulation, shared between all Devices
        time = [];
        
        % List of MGElements currently on the result set
        elementList = [{}];
        
        % Handle of the current plot being shown
        currentPlotHandle = [];
        % Index of the color of the current plot
        currentPlotColor = 1;
        
        % List of variables currently put on hold
        heldPlots = [{}];
    end
    
    methods (Access = private)
        
        function exportData = buildExportData(app)
            % retrieves MGElement from DropDown
            mgElement = app.DropDown.Value;
            
            % retrieves field description from ListBox
            description = app.ListBox.Value{2};
            
            % pre-process source data
            sourceData = util.TypesUtil.emptyCellArray;
            if ~isempty(app.heldPlots)
                sourceData = app.heldPlots(:, [3 4 5]);
            end
            
            % appends the current plot data
            try
                if isvalid(app.currentPlotHandle)
                    sourceData = [sourceData; {app.currentPlotHandle, description, mgElement}];
                end
            catch
                % ignores dumbass isvalid([{}]) error
            end
            
            % adds everything to a big table-like cell array
            exportData = util.TypesUtil.emptyCellArray;
            [plotCount, ~] = size(sourceData);
            
            for i = 1:plotCount
                
                plotHandle  = sourceData{i, 1};
                description = sourceData{i, 2};
                mgElement   = sourceData{i, 3};
                elementId   = mgElement.getId();
                className   = mgElement.getClassName();
                
                if i == 1
                    % time of the first plot goes to the first column
                    exportData = [{'Time (hours from midnight)'}; util.TypesUtil.num2CharCell(plotHandle.XData(:))];
                end
                
                columnTitle = sprintf('%s #%d - %s', className, elementId, description); % should look somthng like "BatteryHan #1 - Power Output [kW]"
                
                values = plotHandle.YData;
                
                columnData = [{columnTitle}; util.TypesUtil.num2CharCell(values(:))];
                exportData = [exportData, columnData];
            end
        end
        
        function outputList = getBatteryOutput(~)
            outputList = ...
                [
                 {'getOperationalCost'       , 'Operational cost over time [$]'}    
                 {'getTotalOperationalCost'  , 'Total operational cost [$]'}             
                 {'getPowerOutput'           , 'Power output [kW]'}             
                 {'getStateOfCharge'         , 'State of charge [%]'}             
                 {'quantity'                 , 'Quantity'}                
                 {'capacity'                 , 'Storage capacity [kWh]'}  
                 {'chargEffi'                , 'Recharge efficiency [%]'}                
                 {'dischEffi'                , 'Discharge efficiency [%]'}               
                 {'maxChargRate'             , 'Maximum recharge rate [kW]'}             
                 {'maxDischRate'             , 'Maximum discharge rate [kW]'}            
                 {'selfDisch'                , 'Rate of self discharge [%]'}             
                 {'socMax'                   , 'Maximum State of Charge allowed  [%]'}   
                 {'socMin'                   , 'Minimum State of Charge allowed  [%]'}   
                 {'totalPrice'               , 'Total battery cost [$]'}
                ];
        end
    
        function outputList = getCentralControllerOutput(~)
            outputList = ...
                [
                 {'getOperationalCost'            , 'Operational cost over time [$]'}         
                 {'getTotalOperationalCost'       , 'Total operational cost [$]'}                  
                 {'getPowerOutput'                , 'Macrogrid power flow [kW]'}                  
                 {'getTime'                       , 'Time [hours from midnight]'}  
                 {'getActualHorizonInHours'       , 'Hours of simulation [hours from t0]'}
                 {'relativeErrorTolerance'        , 'Tolerance [%]'}              
                 {'getHiredDemand'                , 'Contracted power [kW]'}
                 {'t0'                            , 'Simulation start [hour of day]'}
                 {'fineWdayOffPeak'               , 'Demand penalty (off-peak) [$/kW]'}
                 {'fineWdayIntermediate'          , 'Demand penalty (interm) [$/kW]'}
                 {'fineWdayPeak'                  , 'Demand penalty (peak) [$/kW]'}
                 {'tariffPurcWdayOffPeak'         , 'Off-peak tariff (purchase) [$/kWh]'}
                 {'tariffPurcWdayIntermediate'    , 'Intermediate tariff (purchase) [$/kWh]'}
                 {'tariffPurcWdayPeak'            , 'Peak tariff (purchase) [$/kWh]'}
                 {'tariffSellWdayOffPeak'         , 'Off-peak tariff (selling) [$/kWh]'}
                 {'tariffSellWdayIntermediate'    , 'Intermediate tariff (selling) [$/kWh]'}
                 {'tariffSellWdayPeak'            , 'Peak tariff (selling) [$/kWh]'}

                ];
        end
        
        function outputList = getDieselGenQuadOutput(~)
            outputList = ...
                [
                 {'getOperationalCost'         , 'Operational cost over time [$]', {}, {}}
                 {'getTotalOperationalCost'    , 'Total operational cost [$]'    , {}, {}}
                 {'getPowerOutput'             , 'Power output [kW]'             , {}, {}}
                 {'getStates'                  , 'Generator statuses'            , {}, {}}
                 {'quantity'                   , 'Quantity'                      , {}, {}}
                 {'fuelCost'                   , 'Fuel Cost [$/liter]'           , {}, {}}
                 {'getConsumptionModelY'      ,  'Linearization samples [$/kW]'  , 'getConsumptionModelX', 'Power Output [kW]'}
                 {'maxPower'                   , 'Nominal power [kW]'            , {}, {}}
                 {'minRelativePower'           , 'Minimal load [%]'              , {}, {}}
                 {'startupCost'                , 'Startup cost [$]'              , {}, {}}
                 {'a'                          , 'Fuel consumption model (a)'    , {}, {}}
                 {'b'                          , 'Fuel consumption model (b)'    , {}, {}}
                 {'c'                          , 'Fuel consumption model (c)'    , {}, {}}
                 {'linearizationPoints'        , 'Linearization points'          , {}, {}}
                 {'initiallyOn'                , 'Initially on?'                 , {}, {}}
                ];
        end
    
        function outputList = getLoadConstantOutput(~)
            outputList = ...
                [
                 {'getDemandProfile'           , 'Demand profile [kW]'          , {}}                     
                 {'quantity'                   , 'Quantity'                     , {}}
                 {'getOriginalProfile'         , 'Original demand profile [kW]' , 'getOriginalTimeArrayHour'}
                ];
        end
    
        function outputList = getLoadSheddableOutput(~)
            outputList = ...
                [
                 {'getRealizedProfile'         , 'Actual demand profile [kW]'       , {}}                     
                 {'getDemandProfile'           , 'Interpolated demand profile [kW]' , {}}
                 {'getOriginalProfile'         , 'Original demand profile [kW]'     , 'getOriginalTimeArrayHour'}
                 {'quantity'                   , 'Quantity'                         , {}}
                ];
        end
    
        function outputList = getSolarPanelConstantOutput(~)
            outputList = ...
                [
                 {'getOperationalCost'         , 'Operational cost over time [$]'    , {}}
                 {'getTotalOperationalCost'    , 'Total operational cost [$]'        , {}}
                 {'getPowerOutput'             , 'Power output [kW]'                 , {}}                     
                 {'quantity'                   , 'Quantity'                          , {}}
                 {'getOriginalProfile'         , 'Original generation profile [kW]'  , 'getOriginalTimeArrayHour'}
                ];
        end
    
        function updateListBox(app)
            ddValue = app.DropDown.Value;
            
            switch ddValue.getClassName()
                case {'BatteryHan', 'BatteryLinear'}
                    outputList = app.getBatteryOutput();
                case 'CentralController'
                    outputList = app.getCentralControllerOutput();
                case 'DieselGeneratorQuadratic'
                    outputList = app.getDieselGenQuadOutput();
                case 'LoadConstant'
                    outputList = app.getLoadConstantOutput();
                case 'LoadSheddable'
                    outputList = app.getLoadSheddableOutput();
                case 'SolarPanelConstant'
                    outputList = app.getSolarPanelConstantOutput();
                otherwise
                    error('Device class "%s" not recognized', ddValue.getClassName());
            end
            
            % updates the listbox labels
            app.ListBox.Items     = outputList(:, 2);
            
            % updates the list box underlying values
            [outputCount, ~] = size(outputList);
            % transforms the data to a one-dimensional array
            itemsData = [];
            for i = 1:outputCount
                itemsData = [itemsData; {outputList(i, :)}];
            end
            % updates the gui
            app.ListBox.ItemsData = itemsData; 
        end
    
        function str = data2str(app, data)
%             str = sprintf('%d\t%0.4f', 1, data(1));

                assignin("base", "elementList", app.elementList)
            str = sprintf('%0.10g', data(1));
            
            for i = 2:length(data)
%                 str = [str, sprintf('\n%d\t%0.4f', i, data(i))];
                str = [str, sprintf('\n%0.10g', data(i))];
            end
        end
        
        function [index, heldPlot] = getHeldData(app, filterId, filterFieldName)
            [heldCount, ~] = size(app.heldPlots);
            for i = 1:heldCount
                heldId    = app.heldPlots{i, 1};
                heldField = app.heldPlots{i, 2};
                heldPlot  = app.heldPlots{i, 3};
                
                if (heldId == filterId) && strcmp(heldField, filterFieldName)
                    index = i;
                    return;
                end
            end
            
            index     = [];
            heldPlot  = [];
        end
        
        function clone = clonePlotHandle(app, originalHandle, axesHandle)
            XData = originalHandle.XData;
            YData = originalHandle.YData;
            
            Color           = originalHandle.Color;
            LineStyle       = originalHandle.LineStyle;
            LineWidth       = originalHandle.LineWidth;
            Marker          = originalHandle.Marker;
            MarkerSize      = originalHandle.MarkerSize;
            MarkerFaceColor = originalHandle.MarkerFaceColor;
            
            % the function can be overloaded to export to another window
            if nargin >= 3
                hold(axesHandle, 'on');
                clone = stairs(axesHandle, XData, YData, 'Color', Color, 'LineStyle', LineStyle, ...
                    'LineWidth', LineWidth, 'Marker', Marker, 'MarkerSize', MarkerSize, 'MarkerFaceColor', MarkerFaceColor);
                hold(axesHandle, 'off');               
                
                title(axesHandle, app.UIAxes.Title.String);
                xlabel(axesHandle, app.UIAxes.XLabel.String);
                ylabel(axesHandle, app.UIAxes.YLabel.String);
                
                return;
            end
            
            hold(app.UIAxes, 'on');
            clone = stairs(app.UIAxes, XData, YData, 'Color', Color, 'LineStyle', LineStyle, ...
                'LineWidth', LineWidth, 'Marker', Marker, 'MarkerSize', MarkerSize, 'MarkerFaceColor', MarkerFaceColor);
            hold(app.UIAxes, 'off');
        end
        
        function legendList = getLegendList(app)
            mgElement = app.DropDown.Value;
            fieldDesc = app.ListBox.Value{2};
            
            if ~isempty(app.heldPlots)
                legendList = [app.heldPlots(:, 4)];
    
                for i = 1:length(legendList)
                    legendList{i} = sprintf('%s #%d - %s', app.heldPlots{i, 5}.getClassName(), app.heldPlots{i, 5}.getId(), legendList{i});
                end
                
                legendList = [legendList; {sprintf('%s #%d - %s', mgElement.getClassName(), mgElement.getId(), fieldDesc)}];
            else
                legendList = [{sprintf('%s #%d - %s', mgElement.getClassName(), mgElement.getId(), fieldDesc)}];
            end
        end
        
        function updateAxisDxy(app)
%             assignin('base', 'UIAxes', app.UIAxes)
            return;
            
            try
                my_plot.set_dxy(app.UIAxes, 1, 'x');
                my_plot.set_limits(app.UIAxes, [0, Inf], 'x');
            catch e
                disp('Error while updating axis limits: ');
                disp(getReport(e));
            end
        end
        
        function uiBlocked(app, flag)
            if flag
                flag = 'off';
            else
                flag = 'on';
                figure(app.UIFigure);
            end
            
            app.ClearButton.Enable = flag;
            app.HoldButton.Enable = flag;
            app.ExportButton.Enable = flag;
            app.DetachButton.Enable = flag;
            app.ListBox.Enable = flag;
            app.DropDown.Enable = flag;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mgcc)
            try
                % disables UI while loading stuff
                app.uiBlocked(true);
            
                % App name
                app.UIFigure.Name = 'TSChart';            
                
                app.time = mgcc.getTime();
                app.elementList = [{mgcc}; mgcc.getMicrogrid().getMGElements(true)];
                
                % creates the labels of the dropdown
                dropdownItems = {};
                for i = 1:length(app.elementList)
                    itemValue = sprintf('#%d - %s', app.elementList{i}.getId(), app.elementList{i}.getClassName());
                    
                    dropdownItems = [dropdownItems, itemValue];
                end
                
                % inserts labels and data into the dropdown
                app.DropDown.Items     = dropdownItems;
                app.DropDown.ItemsData = app.elementList;
                
                % triggers value changed event
                app.DropDownValueChanged();
                
                %reenables UI
                app.uiBlocked(true);
            catch e
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
                disp(e);
                
                %reenables UI
                app.uiBlocked(true);
            end
            
            app.uiBlocked(false); % comment this line for testing
            return; % comment this line for testing
            
            try
            
                microgrid_model.MGElement.developmentMode(false);
                microgrid_model.MGElement.warningsOn(false);
                
                mgcc = microgrid_model.CentralController();
                battery = microgrid_model.BatteryHan();
                load = microgrid_model.LoadConstant();
                solar = microgrid_model.SolarPanelConstant();
                
                load.importDemandProfile('C:\Users\Lucas Rhode\Desktop\Simu TCC\load profiles\load1.xlsx');
                solar.importGenerationProfile('C:\Users\Lucas Rhode\Desktop\Simu TCC\load profiles\load3.xlsx');
                
                mgcc.addMGElement(battery);
                mgcc.addMGElement(load);
                mgcc.addMGElement(solar);
                mgcc.run();
                
                app.initialize(mgcc);
                
                app.uiBlocked(false);
            catch me
                disp(me);
                app.uiBlocked(false);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            try
                app.updateListBox();
                app.ListBoxValueChanged();
            catch e
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            try
                % retrieves field name and description from ListBox
                fieldName = app.ListBox.Value{1};
                
                % checks if there's any X axis data and uses it
                [~, colCount] = size(app.ListBox.Value);
                if colCount >= 3
                    xDataSource = app.ListBox.Value{3};
                else
                    xDataSource = [];
                end
                if colCount >= 4
                    xLabel = app.ListBox.Value{4};
                else
                    xLabel = 'Hour of day';
                end
                
                % retrieves MGElement from DropDown
                mgElement = app.DropDown.Value;
                
                % reads data from MGElement
                variableValue = mgElement.(fieldName);
                
                % Converts raw variable value to string and displays it
                app.TextArea.Value = app.data2str(variableValue);
                
                % deletes the old plot handle
                try
                    delete(app.currentPlotHandle);
                catch
                    % ignores errors. The only reasonable cause for errors here is trying
                    % to delete a handle that has already been deleted (see
                    % TSChart.HoldButtonValueChanged)
                end
                
                app.HoldButton.Enable = 'on';
                
                % finds out it the current plot is already being held
                heldIndex = app.getHeldData(mgElement.getId(), fieldName);
                
                % if it's already being held, sinks the button and doesn't plot
                % anything
                app.HoldButton.Value = ~isempty(heldIndex);
                if app.HoldButton.Value
                    return;
                end
                
                % calculates the legend
                legendList = app.getLegendList();
                
                % if there's an user-defined XData, uses it
                if ~isempty(xDataSource)
                    xData = mgElement.(xDataSource);
                else
                    xData = app.time;
                end
                
                % converts from logical to numeric
                variableValue = variableValue*1;
                
                % if the variable is scalar, plots a straight line
                if util.TypesUtil.isScalar(variableValue)                    
                    xDataSize = size(xData);
                    variableValue = repmat(variableValue, xDataSize(1), xDataSize(2));
                end
                
                % this prevents the plot from changing colors everytime the selection
                % changes. The only way for the plot line color to change is if
                % the user holds a new plot
                [heldCount, ~] = size(app.heldPlots);
                maxColorIndex = 0;
                for i = 1:heldCount
                    maxColorIndex = max(maxColorIndex, app.heldPlots{i, 6});
                end
                app.UIAxes.ColorOrderIndex = maxColorIndex + 1;
                
                % actually plots everthing
                hold(app.UIAxes, 'on');
                
                app.currentPlotHandle = stairs(app.UIAxes, xData, variableValue, 'LineWidth', 1, 'Marker', 's');
                app.currentPlotColor  = maxColorIndex + 1;
                
                legend(app.UIAxes, legendList{:});
                hold(app.UIAxes, 'off');
                xlabel(app.UIAxes, xLabel);
                
                app.updateAxisDxy();
            catch e
                disp(getReport(e));
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            try
                isHold = app.HoldButton.Value;
                
                mgElement = app.DropDown.Value;
                
                fieldName = app.ListBox.Value{1};
                fieldDescription = app.ListBox.Value{2};
                selectedId = mgElement.getId();
                
                if isHold
                    newPlotHandle = app.clonePlotHandle(app.currentPlotHandle);
                    delete(app.currentPlotHandle);
                    
                    app.heldPlots = [app.heldPlots; {selectedId}, {fieldName}, {newPlotHandle}, {fieldDescription}, {mgElement}, {app.currentPlotColor}];
                    legend(app.UIAxes, app.getLegendList());
                else
                    [index, plot] = app.getHeldData(selectedId, fieldName);
                    if ~isempty(index)
                        delete(plot);
                        app.heldPlots(index, :) = [];
                    end
                
                    app.ListBoxValueChanged();
                end
            catch e
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                exportData = app.buildExportData();
            catch e
                uialert(app.UIFigure, 'Error while exporting your data. Check if all the plots have the same time length.', 'Error');
                disp(getReport(e));
                return;
            end
            
            % alerts the user if there's no data to be exported
            if isempty(exportData)
                uialert(app.UIFigure, 'Nothing to export.', 'No data plotted');
                return;
            end
            
            % exports everything
            try
                filePath = util.FilesUtil.uiPutFile(['ADAMS_Results - ', util.CommonsUtil.getTimestamp(true), '.xls'], 'Export ADAMS data');
                if isempty(filePath)
                    return;
                end
                
                util.FilesUtil.writeExcel(filePath, exportData);
            catch e
                uialert(app.UIFigure, sprintf('Error while exporting your data: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                [heldCount, ~] = size(app.heldPlots);
                
                for i = 1:heldCount
                    delete(app.heldPlots{i, 3});
                end
                
                app.heldPlots = util.TypesUtil.emptyCellArray;
                app.ListBoxValueChanged();
            catch e
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end

        % Button pushed function: DetachButton
        function DetachButtonPushed(app, event)
            try
                plotData = {};
                if ~isempty(app.heldPlots)
                    plotData = app.heldPlots(:, 3);
                end
                
                try
                    if isvalid(app.currentPlotHandle)
                        plotData = [plotData; {app.currentPlotHandle}];
                    end
                catch
                    % ignores dumbass isvalid(<double>) error
                end
                
                [rowcount, ~] = size(plotData);
                
                 % alerts the user if there's no data to be exported
                if rowcount == 0
                    uialert(app.UIFigure, 'Nothing to detach.', 'No data plotted');
                    return;
                end
                
                
                % creates a new figure and a new axes handle
                figureHandle = figure();
                axesHandle = axes(figureHandle);
                
                grid(axesHandle, 'minor');
                
                % clones everything
                for i = 1:rowcount
                    app.clonePlotHandle(plotData{i}, axesHandle);
                end
                
                legend(axesHandle, app.getLegendList());
            catch e
                uialert(app.UIFigure, sprintf('An error occurred: \n %s', regexprep(getReport(e, 'basic'), '<.+?>', '')), 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.DropDownLabel = uilabel(app.GridLayout5);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout5);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DeviceLabel = uilabel(app.GridLayout5);
            app.DeviceLabel.Text = 'Device:';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.HoldButton = uibutton(app.GridLayout4, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Icon = 'hold_icon.png';
            app.HoldButton.Text = 'Hold';
            app.ExportButton = uibutton(app.GridLayout4, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = 'export_icon.png';
            app.ExportButton.Text = 'Export';
            app.ClearButton = uibutton(app.GridLayout4, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'eraser_icon.png';
            app.ClearButton.Text = 'Clear';
            app.DetachButton = uibutton(app.GridLayout4, 'push');
            app.DetachButton.ButtonPushedFcn = createCallbackFcn(app, @DetachButtonPushed, true);
            app.DetachButton.Icon = 'detach_icon.png';
            app.DetachButton.Text = 'Detach';
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Time series plot')
            xlabel(app.UIAxes, 'Hour of day')
            ylabel(app.UIAxes, 'Variable level')
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.TextAreaLabel = uilabel(app.GridLayout6);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GridLayout6);
            app.RawdataLabel = uilabel(app.GridLayout6);
            app.RawdataLabel.Text = 'Raw data:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSChart(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end